classdef PF_Matlab < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        CalculadoraderegresinlinealLabel  matlab.ui.control.Label
        RLabel                        matlab.ui.control.Label
        tabla01                       matlab.ui.control.Table
        CoeficientedecorrelacinLabel  matlab.ui.control.Label
        r2                            matlab.ui.control.Label
        EcuaciondelarectaymxbLabel    matlab.ui.control.Label
        b                             matlab.ui.control.Label
        m                             matlab.ui.control.Label
        bLabel                        matlab.ui.control.Label
        mLabel                        matlab.ui.control.Label
        campoValores                  matlab.ui.control.NumericEditField
        campoValoresLabel             matlab.ui.control.Label
        UIAxes                        matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        function [x, y] = getValores(app)
            % Obtener valores de la tabla para graficar
            datos = app.tabla01.Data;
            x = datos(:, 1);
            y = datos(:, 2);
        end
    end
    methods (Access = public)

        function graficar(app)
          
            [x, y] = app.getValores(); 
            init=10;
 
            scatter(app.UIAxes,x,y,init,"black","filled","o");
              
            hold(app.UIAxes, 'on');
        end
        
        function [tendencia,m,b] = regresionLineal(app)

            [x, y] = app.getValores(); 

             % Regresion lineal
                    p = polyfit(x, y, 1);
                    b = p(2);
                    m = p(1);
                    tendencia = b + m*x;
        end
        
        function actualizar(app)
            
            [x, y] = app.getValores(); 
            
            % Para eliminar graficas existentes
            cla(app.UIAxes);

            
            graficar(app);

            % Calcular la regresion lineal
            [tendencia, m, b] = regresionLineal(app);
            
            % Etiquetas
            app.b.Text=sprintf('%.6f',b);
            app.m.Text=sprintf('%.6f',m);
            
            % Mostrar la linea de tendencia
            plot(app.UIAxes,app.tabla01.Data(:,1),tendencia);
            
            hold(app.UIAxes, 'off');

            setLimites(app);

            % Coeficiente de determinación
            r2 = coeficienteDet(app,y,tendencia);
            app.r2.Text= sprintf('%.6f',r2);
        end

        function setLimites(app)
            
            [x, y] = app.getValores();

            % Para obtener los minimos
            xMin = min(x);
            yMin = min(y);
            
            % Casos
            if(max(x) ~= xMin)
                rangoX = max(x) - xMin;
            % Si tenemos los mismos valores: rango = 1
            else
                rangoX = 1;
            end
            if(max(y) ~= yMin)
                rangoY = max(y) - yMin;
            else
                rangoY = 1;
            end
            % Establecer los limites dinámicamente
            app.UIAxes.XLim = [xMin - 0.1 * rangoX, max(x) + 0.1 * rangoX];
            app.UIAxes.YLim = [yMin - 0.1 * rangoY, max(y) + 0.1 * rangoY];
        end
        
        function rSquared = coeficienteDet(~, y, tendencia)
            % Calcular la media de y
            yMedia = mean(y);

            % Calcular la suma total de cuadrados
            stc = sum((y - yMedia).^2);

            % Calcular la suma residual de cuadrados
            src = sum((y - tendencia).^2);

            % Calcular el coeficiendte de determinación (R-squared)
            rSquared = 1 - src/stc;
        end
        
        function inicializar(app)
            % Limpiar registros
            cla(app.UIAxes);
          
            app.tabla01.ColumnName=["X","Y"];
            % Stablish default values
            app.campoValores.Value=2;
            for i=1:2
                app.tabla01.Data(i,1) = 0;
                app.tabla01.Data(i,2) = 0;
            end
            actualizar(app);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            inicializar(app);
        end

        % Cell edit callback: tabla01
        function tabla01CellEdit(app, event)
            actualizar(app);
        end

        % Value changed function: campoValores
        function campoValoresValueChanged(app, event)
            % Store new and old number of columns
            datos = app.tabla01.Data;
            filasAcutales = size(datos,1);
            filasRequeridas = app.campoValores.Value;

            if(filasAcutales < filasRequeridas)
                for i=filasAcutales:filasRequeridas
                    app.tabla01.Data(i,1) = 0;
                    app.tabla01.Data(i,2) = 0;
                end
                
                actualizar(app);
            
            elseif (filasAcutales > filasRequeridas)
                for i=filasRequeridas:filasAcutales-1
                    app.tabla01.Data(end,:)=[];
                end
                actualizar(app);
            end

            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Gráfica')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            colormap(app.UIAxes, 'turbo')
            app.campoValoresLabel = uilabel(app.UIFigure);
            app.campoValoresLabel.Text = 'Cantidad de valores a evaluar';
            app.campoValores = uieditfield(app.UIFigure, 'numeric');
            app.campoValores.Limits = [2 Inf];
            app.campoValores.RoundFractionalValues = 'on';
            app.campoValores.ValueChangedFcn = createCallbackFcn(app, @campoValoresValueChanged, true);
            app.campoValores.Value = 2;
            app.mLabel = uilabel(app.UIFigure);
            app.mLabel.Text = 'm:';
            app.bLabel = uilabel(app.UIFigure);
            app.bLabel.Text = 'b:';
            app.m = uilabel(app.UIFigure);
            app.m.Text = '0';
            app.b = uilabel(app.UIFigure);
            app.b.Text = '0';
            app.EcuaciondelarectaymxbLabel = uilabel(app.UIFigure);
            app.EcuaciondelarectaymxbLabel.Text = 'Ecuacion de la recta (y=mx+b) : ';
            app.r2 = uilabel(app.UIFigure);
            app.r2.Text = '0';
            app.CoeficientedecorrelacinLabel = uilabel(app.UIFigure);
            app.CoeficientedecorrelacinLabel.Text = 'Coeficiente de correlación:';
            app.tabla01 = uitable(app.UIFigure);
            app.tabla01.ColumnName = {'X'; 'Y'};
            app.tabla01.RowName = {};
            app.tabla01.ColumnEditable = [true true];
            app.tabla01.CellEditCallback = createCallbackFcn(app, @tabla01CellEdit, true);
            app.tabla01.Multiselect = 'off';
            app.RLabel = uilabel(app.UIFigure);
            app.RLabel.Text = 'R² : ';
            app.CalculadoraderegresinlinealLabel = uilabel(app.UIFigure);
            app.CalculadoraderegresinlinealLabel.Text = 'Calculadora de regresión lineal';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PF_Matlab

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end